classdef SGLC_Calc_V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        VisualizationPanel             matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        RxPowerSlantRangevsElevationTab  matlab.ui.container.Tab
        UIAxes1                        matlab.ui.control.UIAxes
        LinkMarginvsWeatherTab         matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        LossBreakdownTab               matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        UITable                        matlab.ui.control.Table
        LinkBudgetAnalysisPanel        matlab.ui.container.Panel
        Panel_6                        matlab.ui.container.Panel
        SummaryCardPanel               matlab.ui.container.Panel
        LinkMarginLabel                matlab.ui.control.Label
        RxPowerLabel                   matlab.ui.control.Label
        TransceiverTextAreaLabel_2     matlab.ui.control.Label
        ChannelEnvironmentPanel        matlab.ui.container.Panel
        LinkDirectionLabel             matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        WeatherTab                     matlab.ui.container.Tab
        SnowTypeButtonGroup            matlab.ui.container.ButtonGroup
        WetSnowButton                  matlab.ui.control.RadioButton
        DrySnowButton                  matlab.ui.control.RadioButton
        ParameterLabel                 matlab.ui.control.Label
        ParameterField                 matlab.ui.control.NumericEditField
        GeometryTextArea_2             matlab.ui.control.TextArea
        GeometryTextAreaLabel_2        matlab.ui.control.Label
        WeatherDropDownValueChanged    matlab.ui.control.DropDown
        WeatherConditionDropDownLabel  matlab.ui.control.Label
        TurbulenceTab                  matlab.ui.container.Tab
        TurbulenceLamp                 matlab.ui.control.Lamp
        TurbulenceModelSwitch          matlab.ui.control.Switch
        TurbulenceModelSwitchLabel     matlab.ui.control.Label
        HV57Label                      matlab.ui.control.Label
        RMSWindSpeedvmsEditField       matlab.ui.control.NumericEditField
        RMSWindSpeedvmsEditFieldLabel  matlab.ui.control.Label
        GeometryTextAreaLabel_4        matlab.ui.control.Label
        GeometryTextArea_4             matlab.ui.control.TextArea
        Panel_4                        matlab.ui.container.Panel
        Switch_2                       matlab.ui.control.ToggleSwitch
        ExportResultsButton            matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        RunSimulationButton            matlab.ui.control.Button
        SystemConfigurationPanel       matlab.ui.container.Panel
        SatelliteLabel                 matlab.ui.control.Label
        GlobalParametersLabel          matlab.ui.control.Label
        GroundStationLabel             matlab.ui.control.Label
        PointingErrorGSEditField       matlab.ui.control.NumericEditField
        PointingError_GsradLabel       matlab.ui.control.Label
        AltitudeGSEditField            matlab.ui.control.NumericEditField
        AltitudekmLabel                matlab.ui.control.Label
        OrbitAltitudeSatEditField      matlab.ui.control.NumericEditField
        OrbitAltitudekmLabel           matlab.ui.control.Label
        PointingErrorSatEditField      matlab.ui.control.NumericEditField
        OrbitAltitudeHkmLabel          matlab.ui.control.Label
        OpticalEfficiencySatEditField  matlab.ui.control.NumericEditField
        SystemLossL_sysdBLabel         matlab.ui.control.Label
        AntennaApertureSatEditField    matlab.ui.control.NumericEditField
        RxApertureD_rxmLabel           matlab.ui.control.Label
        OpticalEfficiencyGSEditField   matlab.ui.control.NumericEditField
        TxApertureD_txmLabel           matlab.ui.control.Label
        AntennaApertureGSEditField     matlab.ui.control.NumericEditField
        TxPowerP_txWLabel              matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        Panel_2                        matlab.ui.container.Panel
        WavelengthEditField            matlab.ui.control.NumericEditField
        WavelengthnmLabel              matlab.ui.control.Label
        TransmitPowerEditField         matlab.ui.control.NumericEditField
        TxDivergence_txradLabel        matlab.ui.control.Label
        Knob_2                         matlab.ui.control.Knob
        ElevationAngleEditField        matlab.ui.control.NumericEditField
        TxDivergence_txradLabel_2      matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        Panel_5                        matlab.ui.container.Panel
        RequiredSignalPowerdBmEditField  matlab.ui.control.NumericEditField
        RequiredSignalPowerdBmEditFieldLabel  matlab.ui.control.Label
        SatelliteGroundLaserCommunicationCalculatorv10Label  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'SGLC-Calc: Satellite-Ground Laser Communication Calculator';
            app.SnowTypeButtonGroup.Visible = 'off';
            app.TurbulenceLamp.Color = [0.90, 0.90, 0.90];
            app.ParameterField.Editable = 'on';
        end

        % Value changed function: WeatherDropDownValueChanged
        function WeatherDropDownValueChangedValueChanged(app, event)
            value = app.WeatherDropDownValueChanged.Value;
            app.ParameterField.Editable = 'on';
            app.ParameterField.Enable = 'on';
            app.SnowTypeButtonGroup.Visible = 'off';
            switch value
                case 'Clear Sky'
                    app.ParameterLabel.Text = 'Extra Attenuation [dB]';
                    app.ParameterField.Value = 0;
                    app.ParameterLabel.Visible = 'on';
                    app.ParameterField.Visible = 'on';
                    app.GeometryTextArea_2.Visible = 'on';
                    app.GeometryTextAreaLabel_2.Visible = 'on';


                case 'Fog'
                    app.ParameterLabel.Text = 'Visibility (V) [km]';
                    app.ParameterField.Value = 50;
                    app.ParameterLabel.Visible = 'on';
                    app.ParameterField.Visible = 'on';
                    app.GeometryTextArea_2.Visible = 'on';
                    app.GeometryTextAreaLabel_2.Visible = 'on';

                case 'Rain'
                    app.ParameterLabel.Text = 'Rain Rate (R) [mm/h]';
                    app.ParameterField.Value = 2;
                    app.ParameterLabel.Visible = 'on';
                    app.ParameterField.Visible = 'on';
                    app.GeometryTextArea_2.Visible = 'on';
                    app.GeometryTextAreaLabel_2.Visible = 'on';

                case 'Snow'
                    app.ParameterLabel.Text = 'Snow Rate (S) [mm/h]';
                    app.ParameterField.Value = 1;
                    app.SnowTypeButtonGroup.Visible = 'on';
                    app.ParameterLabel.Visible = 'on';
                    app.ParameterField.Visible = 'on';
                    app.GeometryTextArea_2.Visible = 'on';
                    app.GeometryTextAreaLabel_2.Visible = 'on';

                case 'Clouds'
                    app.ParameterLabel.Visible = 'off';
                    app.ParameterField.Visible = 'off';
                    app.GeometryTextArea_2.Visible = 'off';
                    app.GeometryTextAreaLabel_2.Visible = 'off';
            end
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            p = struct();
            GS = struct();
            Sat = struct();
            p.LinkType = app.Switch_2.Value;
            p.lambda = app.WavelengthEditField.Value * 1e-9;
            p.P_tx = app.TransmitPowerEditField.Value;
            p.theta_el = app.ElevationAngleEditField.Value;

            %Weather_Parameters
            p.WeatherType = app.WeatherDropDownValueChanged.Value;
            p.Weather_Parameters = app.ParameterField.Value;
            p.v_rms = app.RMSWindSpeedvmsEditField.Value;
            p.sensitivity = app.RequiredSignalPowerdBmEditField.Value;

            % --- Ground Station (GS) ---
            GS.H = app.AltitudeGSEditField.Value * 1e3;
            GS.D = app.AntennaApertureGSEditField.Value;
            GS.eta = 10^(app.OpticalEfficiencyGSEditField.Value / 10);
            GS.gamma = app.PointingErrorGSEditField.Value;

            % --- Satellite (Sat) ---
            Sat.H = app.OrbitAltitudeSatEditField.Value * 1e3;
            Sat.D = app.AntennaApertureSatEditField.Value;
            Sat.eta = 10^(app.OpticalEfficiencySatEditField.Value / 10);
            Sat.gamma = app.PointingErrorSatEditField.Value;
            switchValue = app.TurbulenceModelSwitch.Value;

            if strcmp(switchValue, 'On')
                p.isTurbulenceEnabled = true;
            else
                p.isTurbulenceEnabled = false;
            end

            if strcmp(p.WeatherType, 'Snow')

                selectedBtn = app.SnowTypeButtonGroup.SelectedObject;


                if selectedBtn == app.WetSnowButton
                    p.isWetSnow = true;
                else
                    p.isWetSnow = false;
                end
            else
                p.isWetSnow = false; 
            end


            results = calc_SGLC_LinkBudget(p,GS,Sat);


            weatherLabel = 'Weather Loss';
            switch p.WeatherType
                case 'Rain',      weatherLabel = 'Rain Attenuation';
                case 'Snow',      weatherLabel = 'Snow Attenuation';
                case 'Fog',       weatherLabel = 'Fog Attenuation';
                case 'Clouds',    weatherLabel = 'Cloud Attenuation';
                case 'Clear Sky', weatherLabel = 'Clear Sky Loss';
            end


            total_weather_loss = results.LossBreakdown.Fog + ...
                results.LossBreakdown.Rain + ...
                results.LossBreakdown.Cloud + ...
                results.LossBreakdown.Snow;


            total_optical_efficiency = -10 * log10(GS.eta * Sat.eta);


            tableData = {

            'Slant Range',              sprintf('%.2f km', results.SlantRange_km);
            'Free Space Loss (FSPL)',   sprintf('%.2f dB', results.FSPL_dB);


            'Total Pointing Loss',      sprintf('%.2f dB', results.TotalPointingLoss_dB);
            'Optical Antenna Efficiency',    sprintf('%.2f dB', total_optical_efficiency);


            weatherLabel,               sprintf('%.2f dB', total_weather_loss); % 动态天气
            'Turbulence Loss',          sprintf('%.2f dB', results.LossBreakdown.Turbulence);

            'Transmitter Gain',          sprintf('%.2f dB', results.TxGain_dB);
            'Receiver Gain',          sprintf('%.2f dB', results.RxGain_dB);
            };


            app.UITable.Data = tableData;

            el_scan = 20:1:90; 
            curve_rx_power = zeros(size(el_scan));
            curve_margin   = zeros(size(el_scan));
            curve_slant_range = zeros(size(el_scan));

            for i = 1:length(el_scan)
                p_temp = p;
                p_temp.theta_el = el_scan(i);
                try
                    res_temp = calc_SGLC_LinkBudget(p_temp, GS, Sat);
                    curve_rx_power(i) = res_temp.ReceivedPower_dBm;
                    curve_margin(i)   = res_temp.LinkMargin_dB;
                    curve_slant_range(i) = res_temp.SlantRange_km;
                catch
                    curve_rx_power(i) = NaN;
                    curve_margin(i)   = NaN;
                    curve_slant_range(i) = NaN;
                end
            end

            cla(app.UIAxes1);
            reset(app.UIAxes1);
            hold(app.UIAxes1, 'on');

            yyaxis(app.UIAxes1, 'left');
            
            plot(app.UIAxes1, el_scan, curve_rx_power, 'LineWidth', 2, 'Color', '#0072BD', 'DisplayName', 'Rx Power');
           
            if isfield(p, 'sensitivity')
                sens_val = p.sensitivity;
            else
                sens_val = -45; 
            end
            yline(app.UIAxes1, sens_val, '--r', sprintf('Sensitivity (%.1fdBm)', sens_val), ...
                'LineWidth', 1.5, 'LabelHorizontalAlignment', 'left');
           
            app.UIAxes1.YColor = '#0072BD'; 
            app.UIAxes1.YLabel.String = 'Rx Power [dBm]';
            app.UIAxes1.YLabel.Color = '#0072BD';

            yyaxis(app.UIAxes1, 'right');
            
            plot(app.UIAxes1, el_scan, curve_slant_range, 'LineWidth', 2, 'Color', '#D95319', 'DisplayName', 'Slant Range');
        
            app.UIAxes1.YColor = '#D95319'; 
            app.UIAxes1.YLabel.String = 'Slant Range [km]';
            app.UIAxes1.YLabel.Color = '#D95319';

            app.UIAxes1.Title.String = 'Rx Power & Slant Range vs. Elevation';
            app.UIAxes1.XLabel.String = 'Elevation Angle [deg]';
            app.UIAxes1.XLim = [20 90];
            grid(app.UIAxes1, 'on');
            legend(app.UIAxes1, 'Location', 'best'); 
            hold(app.UIAxes1, 'off');

            cla(app.UIAxes2);
            reset(app.UIAxes2);
            plot(app.UIAxes2, el_scan, curve_margin, 'LineWidth', 2, 'Color', '#77AC30');
            hold(app.UIAxes2, 'on');
            yline(app.UIAxes2, 3, '--r', 'Safety Margin (3dB)', 'LineWidth', 1.5);
            yline(app.UIAxes2, 0, '-k', 'Critical (0dB)', 'LineWidth', 1);
            app.UIAxes2.Title.String = 'Link Margin Analysis';
            app.UIAxes2.XLabel.String = 'Elevation Angle [deg]';
            app.UIAxes2.YLabel.String = 'Margin [dB]';
            app.UIAxes2.XLim = [20 90];
            grid(app.UIAxes2, 'on');
            hold(app.UIAxes2, 'off');

            cla(app.UIAxes3);
            reset(app.UIAxes3);

            val_fspl = results.FSPL_dB;

            val_pointing = results.TotalPointingLoss_dB;

            val_optical = -10 * log10(Sat.eta * GS.eta);

            val_weather = results.LossBreakdown.Fog + results.LossBreakdown.Rain + ...
                results.LossBreakdown.Cloud + results.LossBreakdown.Snow ;
            val_turb = results.LossBreakdown.Turbulence;
            loss_values = [val_fspl, val_pointing, val_optical, val_weather, val_turb];
            loss_names = {'FSPL', 'Pointing', 'Optical', 'Weather', 'Turbulence'};
            raw_colors = [
                0.00, 0.45, 0.74; % FSPL
                0.85, 0.33, 0.10; % Pointing
                0.93, 0.69, 0.13; % Optical
                0.47, 0.67, 0.19; % Weather
                0.49, 0.18, 0.56  % Turbulence
                ];

            [sorted_values, sort_idx] = sort(loss_values, 'descend');

            sorted_names = loss_names(sort_idx);
            sorted_colors = raw_colors(sort_idx, :);

            x_cats = categorical(sorted_names);
            x_cats = reordercats(x_cats, sorted_names);

            b = bar(app.UIAxes3, x_cats, sorted_values);

            b.FaceColor = 'flat';
            b.CData = sorted_colors; 

            xtips = b.XEndPoints;
            ytips = b.YEndPoints;
            labels_str = string(round(sorted_values, 2)) + " dB";

            text(app.UIAxes3, xtips, ytips, labels_str, ...
                'HorizontalAlignment', 'center', ...
                'VerticalAlignment', 'bottom', ...
                'FontSize', 10, 'FontWeight', 'bold');

            app.UIAxes3.Title.String = 'Loss Breakdown (Sorted by Magnitude)';
            app.UIAxes3.YLabel.String = 'Loss [dB]';
            app.UIAxes3.YGrid = 'on';

            if max(sorted_values) > 0
                app.UIAxes3.YLim = [0, max(sorted_values) * 1.15];
            else
                app.UIAxes3.YLim = [0, 1]; 
            end

            app.RxPowerLabel.Text = sprintf('Rx Power: %.2f dBm', results.ReceivedPower_dBm);
            app.LinkMarginLabel.Text = sprintf('Link Margin: %.2f dB', results.LinkMargin_dB);
            app.RxPowerLabel.Text = sprintf('Rx Power: %.2f dBm', results.ReceivedPower_dBm);
            app.LinkMarginLabel.Text = sprintf('Link Margin: %.2f dB', results.LinkMargin_dB);

        end

        % Value changing function: Knob_2
        function Knob_2ValueChanging(app, event)
            app.ElevationAngleEditField.Value = event.Value;
        end

        % Value changed function: TurbulenceModelSwitch
        function TurbulenceModelSwitchValueChanged(app, event)
            status = app.TurbulenceModelSwitch.Value;
            if strcmp(status, 'On')

                app.TurbulenceLamp.Color = [0.39, 0.83, 0.07];
            else

                app.TurbulenceLamp.Color = [0.90, 0.90, 0.90];
            end
        end

        % Value changed function: ElevationAngleEditField
        function ElevationAngleEditFieldValueChanged(app, event)
            value = app.ElevationAngleEditField.Value;
            if value < 20 || value > 90
                app.ElevationAngleEditField.Value = 55;
                app.Knob_2.Value = 55;
                message = sprintf('Elevation angle input error!\nValid range: 20°~90° (too low causes link interruption)');
                uialert(app.UIFigure, message, 'Parameter Error', 'Icon', 'error');
                return;
            end

            app.Knob_2.Value = value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.WavelengthEditField.Value = 1550;
            app.TransmitPowerEditField.Value = 20;      
            app.ElevationAngleEditField.Value = 45;     
            app.Knob_2.Value = 55;                      
            app.AltitudeGSEditField.Value = 0.1;
            app.AntennaApertureGSEditField.Value = 0.8; 
            app.OpticalEfficiencyGSEditField.Value = -0.97; 
            app.PointingErrorGSEditField.Value = 1e-6; 
            app.OrbitAltitudeSatEditField.Value = 500; 
            app.AntennaApertureSatEditField.Value = 0.08; 
            app.OpticalEfficiencySatEditField.Value = -0.97; 
            app.PointingErrorSatEditField.Value = 1e-6; 
            app.RequiredSignalPowerdBmEditField.Value = -36; 
            app.RMSWindSpeedvmsEditField.Value = 21; 
            app.WeatherDropDownValueChanged.Value = 'Clear Sky'; 
            app.WeatherDropDownValueChangedValueChanged();
            app.TurbulenceModelSwitch.Value = 'Off';    
            app.TurbulenceLamp.Color = [0.90, 0.90, 0.90]; 
            app.Switch_2.Value = 'Uplink (Ground-to-Sat)';
            app.RxPowerLabel.Text = 'Rx Power: 0 dBm';
            app.LinkMarginLabel.Text = 'Link Margin: 0 dB';
            app.UITable.Data = {};
            cla(app.UIAxes1); reset(app.UIAxes1); title(app.UIAxes1, 'Rx Power vs. Elevation');
            cla(app.UIAxes2); reset(app.UIAxes2); title(app.UIAxes2, 'Link Margin Analysis');
            cla(app.UIAxes3); reset(app.UIAxes3); title(app.UIAxes3, 'Loss Breakdown');
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)

            if isempty(app.UITable.Data)
                uialert(app.UIFigure, 'No simulation data to export. Please run simulation first.', 'Warning');
                return;
            end

            selPath = uigetdir(pwd, 'Select a folder to save results');

            if selPath == 0
                return;
            end

            timestamp = char(datetime('now', 'Format', 'yyyyMMdd_HHmmss'));
            folderName = sprintf('SGLC_Result_%s', timestamp);
            fullFolderPath = fullfile(selPath, folderName);
            status = mkdir(fullFolderPath);

            if ~status
                uialert(app.UIFigure, 'Failed to create folder. Check permissions.', 'Error');
                return;
            end

            try

                file1 = fullfile(fullFolderPath, 'Fig1_RxPower_vs_Elevation.png');
                exportgraphics(app.UIAxes1, file1, 'Resolution', 600, 'BackgroundColor', 'white');

                file2 = fullfile(fullFolderPath, 'Fig2_LinkMargin_Analysis.png');
                exportgraphics(app.UIAxes2, file2, 'Resolution', 600, 'BackgroundColor', 'white');

                file3 = fullfile(fullFolderPath, 'Fig3_Loss_Breakdown.png');
                exportgraphics(app.UIAxes3, file3, 'Resolution', 600, 'BackgroundColor', 'white');

            catch ME
                uialert(app.UIFigure, ['Image export failed: ' ME.message], 'Error');
            end

            excelFileName = fullfile(fullFolderPath, 'Simulation_Report.xlsx');

            input_params = {
                '--- Global Params ---', '';
                'Wavelength [nm]', app.WavelengthEditField.Value;
                'Tx Power [dBm]', app.TransmitPowerEditField.Value;
                'Elevation Angle [deg]', app.ElevationAngleEditField.Value;
                'Link Direction', app.Switch_2.Value;
                'Required Sensitivity [dBm]', app.RequiredSignalPowerdBmEditField.Value;
                '--- Weather ---', '';
                'Condition', app.WeatherDropDownValueChanged.Value;
                'Param Value', app.ParameterField.Value;
                'Turbulence Enabled', app.TurbulenceModelSwitch.Value;
                'Wind Speed [m/s]', app.RMSWindSpeedvmsEditField.Value;
                '--- Ground Station ---', '';
                'Altitude [km]', app.AltitudeGSEditField.Value;
                'Aperture [m]', app.AntennaApertureGSEditField.Value;
                'Efficiency [dB]', app.OpticalEfficiencyGSEditField.Value;
                'Pointing Error [rad]', app.PointingErrorGSEditField.Value;
                '--- Satellite ---', '';
                'Altitude [km]', app.OrbitAltitudeSatEditField.Value;
                'Aperture [m]', app.AntennaApertureSatEditField.Value;
                'Efficiency [dB]', app.OpticalEfficiencySatEditField.Value;
                'Pointing Error [rad]', app.PointingErrorSatEditField.Value;
                };

            
            T_inputs = cell2table(input_params, 'VariableNames', {'Parameter', 'Value'});

            tableData = app.UITable.Data;
            
            if isempty(tableData)
                T_outputs = table();
            else
                T_outputs = cell2table(tableData, 'VariableNames', {'Result_Parameter', 'Result_Value'});
            end

            try
                writetable(T_inputs, excelFileName, 'Sheet', 'Input_Parameters');
                writetable(T_outputs, excelFileName, 'Sheet', 'Budget_Results');
                msg = sprintf('Export Successful!\nSaved to folder:\n%s', folderName);
                uialert(app.UIFigure, msg, 'Success');

            catch ME
                uialert(app.UIFigure, ['Excel export failed: ' ME.message], 'Error');
            end

        end

        % Value changed function: WavelengthEditField
        function WavelengthEditFieldValueChanged(app, event)

    value = app.WavelengthEditField.Value;
    if value <= 0 || value < 800 || value > 2000
        message = sprintf('Wavelength input error!\nValid range: 800~2000 nm (positive number)');
        uialert(app.UIFigure, message, 'Parameter Error', 'Icon', 'error');
        app.WavelengthEditField.Value = 1550;
    end
        end

        % Value changed function: AltitudeGSEditField
        function AltitudeGSEditFieldValueChanged(app, event)
            value = app.AltitudeGSEditField.Value;
            satAlt = app.OrbitAltitudeSatEditField.Value;

            if value < 0

                message = sprintf('Ground station altitude input error!\nValid range: ≥0 km');
                uialert(app.UIFigure, message, 'Parameter Error', 'Icon', 'error');
                app.AltitudeGSEditField.Value = 0.1;

            elseif value >= satAlt && satAlt > 0

                message = sprintf('Ground station altitude input error!\nMust be less than satellite altitude (current satellite altitude: %g km)', satAlt);
                uialert(app.UIFigure, message, 'Parameter Error', 'Icon', 'error');
                app.AltitudeGSEditField.Value = 0.1;
            end
        end

        % Value changed function: TransmitPowerEditField
        function TransmitPowerEditFieldValueChanged(app, event)
            value = app.TransmitPowerEditField.Value;
            if value <= 0
                message = sprintf('Transmit power input error!\nValid range: greater than 0 dBm');
                uialert(app.UIFigure, message, 'Parameter Error', 'Icon', 'error');
                app.TransmitPowerEditField.Value = 20;
            end
        end

        % Value changed function: AntennaApertureGSEditField
        function AntennaApertureGSEditFieldValueChanged(app, event)
            value = app.AntennaApertureGSEditField.Value;
            if value <= 0
                message = sprintf('Ground station antenna aperture input error!\nValid range: >0 m');
                uialert(app.UIFigure, message, 'Parameter Error', 'Icon', 'error');
                app.AntennaApertureGSEditField.Value = 0.8; % Reset to default 0.8 m
            end
        end

        % Value changed function: OpticalEfficiencyGSEditField
        function OpticalEfficiencyGSEditFieldValueChanged(app, event)
            value = app.OpticalEfficiencyGSEditField.Value;
            if value > 0
                message = sprintf('Ground station efficiency (dB mode) input error!\nValid range: ≤ 0 dB (0%% - 100%%)');
                uialert(app.UIFigure, message, 'Parameter Error', 'Icon', 'error');
                app.OpticalEfficiencyGSEditField.Value = -0.97; % Reset to -0.97 dB (corresponds to 0.8)
            end
        end

        % Value changed function: PointingErrorGSEditField
        function PointingErrorGSEditFieldValueChanged(app, event)
            value = app.PointingErrorGSEditField.Value;
            if value <= 0 || value > 1e-3
                message = sprintf('Ground station pointing error input error!\nValid range: >0 and ≤1e-3 rad');
                uialert(app.UIFigure, message, 'Parameter Error', 'Icon', 'error');
                app.PointingErrorGSEditField.Value = 1e-6;
            end
        end

        % Value changed function: OrbitAltitudeSatEditField
        function OrbitAltitudeSatEditFieldValueChanged(app, event)

            value = app.OrbitAltitudeSatEditField.Value;
            gsAlt = app.AltitudeGSEditField.Value;
            if value <= 0

                msg = sprintf('Satellite altitude input error!\nValid range: greater than 0 km');
                uialert(app.UIFigure, msg, 'Parameter Error', 'Icon', 'error');
                app.OrbitAltitudeSatEditField.Value = 500;

            elseif value < 100

                msg = sprintf('Satellite altitude input error!\nMinimum LEO satellite altitude: ≥100 km');
                uialert(app.UIFigure, msg, 'Parameter Error', 'Icon', 'error');
                app.OrbitAltitudeSatEditField.Value = 500;

            elseif value <= gsAlt

                msg = sprintf('Satellite altitude input error!\nMust be greater than ground station altitude (current: %g km)', gsAlt);
                uialert(app.UIFigure, msg, 'Parameter Error', 'Icon', 'error');
                app.OrbitAltitudeSatEditField.Value = 500;
            end


        end

        % Value changed function: AntennaApertureSatEditField
        function AntennaApertureSatEditFieldValueChanged(app, event)
            value = app.AntennaApertureSatEditField.Value;
            if value < 0.01
                msg = sprintf('Satellite antenna aperture input error!\nValid range: ≥0.01 m (minimum practical)');
                uialert(app.UIFigure, msg, 'Parameter Error', 'Icon', 'error');

                app.AntennaApertureSatEditField.Value = 0.08;
            end

        end

        % Value changed function: OpticalEfficiencySatEditField
        function OpticalEfficiencySatEditFieldValueChanged(app, event)
            value = app.OpticalEfficiencySatEditField.Value;

            if value > 0

                msg = sprintf('Satellite efficiency (dB mode) input error!\nValid range: ≤ 0 dB (corresponds to 0%%-100%%)');
                uialert(app.UIFigure, msg, 'Parameter Error', 'Icon', 'error');
                app.OpticalEfficiencySatEditField.Value = -0.97;
            end

        end

        % Value changed function: PointingErrorSatEditField
        function PointingErrorSatEditFieldValueChanged(app, event)
            value = app.PointingErrorSatEditField.Value;

            if value <= 0 || value > 1e-3

                message = sprintf('Satellite pointing error input error!\nValid range: >0 and ≤0.001 rad (1 mrad)');
                uialert(app.UIFigure, message, 'Parameter Error', 'Icon', 'error');

                app.PointingErrorSatEditField.Value = 1e-6;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SatelliteGroundLaserCommunicationCalculatorv10Label = uilabel(app.UIFigure);
            app.SatelliteGroundLaserCommunicationCalculatorv10Label.Text = ' Satellite-Ground Laser Communication Calculator v1.0';
            app.SystemConfigurationPanel = uipanel(app.UIFigure);
            app.SystemConfigurationPanel.Title = 'System Configuration';
            app.Panel_5 = uipanel(app.SystemConfigurationPanel);
            app.RequiredSignalPowerdBmEditFieldLabel = uilabel(app.Panel_5);
            app.RequiredSignalPowerdBmEditFieldLabel.Text = 'Required Signal Power(dBm)';
            app.RequiredSignalPowerdBmEditField = uieditfield(app.Panel_5, 'numeric');
            app.RequiredSignalPowerdBmEditField.Value = -36;
            app.Panel_3 = uipanel(app.SystemConfigurationPanel);
            app.Panel_2 = uipanel(app.SystemConfigurationPanel);
            app.TxDivergence_txradLabel_2 = uilabel(app.Panel_2);
            app.TxDivergence_txradLabel_2.Text = 'Elevation Angle [deg]';
            app.ElevationAngleEditField = uieditfield(app.Panel_2, 'numeric');
            app.ElevationAngleEditField.ValueChangedFcn = createCallbackFcn(app, @ElevationAngleEditFieldValueChanged, true);
            app.ElevationAngleEditField.Value = 55;
            app.Knob_2 = uiknob(app.Panel_2, 'continuous');
            app.Knob_2.Limits = [20 90];
            app.Knob_2.MajorTicks = [20 30 40 50 60 70 80 90];
            app.Knob_2.ValueChangingFcn = createCallbackFcn(app, @Knob_2ValueChanging, true);
            app.Knob_2.Value = 55;
            app.TxDivergence_txradLabel = uilabel(app.Panel_2);
            app.TxDivergence_txradLabel.Text = 'Transmit Power [dBm] ';
            app.TransmitPowerEditField = uieditfield(app.Panel_2, 'numeric');
            app.TransmitPowerEditField.ValueChangedFcn = createCallbackFcn(app, @TransmitPowerEditFieldValueChanged, true);
            app.TransmitPowerEditField.Value = 20;
            app.WavelengthnmLabel = uilabel(app.Panel_2);
            app.WavelengthnmLabel.Text = 'Wavelength [nm] ';
            app.WavelengthEditField = uieditfield(app.Panel_2, 'numeric');
            app.WavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthEditFieldValueChanged, true);
            app.WavelengthEditField.Value = 1550;
            app.Panel = uipanel(app.SystemConfigurationPanel);
            app.TxPowerP_txWLabel = uilabel(app.SystemConfigurationPanel);
            app.TxPowerP_txWLabel.Text = 'Antenna Aperture [m]';
            app.AntennaApertureGSEditField = uieditfield(app.SystemConfigurationPanel, 'numeric');
            app.AntennaApertureGSEditField.ValueChangedFcn = createCallbackFcn(app, @AntennaApertureGSEditFieldValueChanged, true);
            app.AntennaApertureGSEditField.Value = 0.8;
            app.TxApertureD_txmLabel = uilabel(app.SystemConfigurationPanel);
            app.TxApertureD_txmLabel.Text = 'Optical Efficiency [dB]';
            app.OpticalEfficiencyGSEditField = uieditfield(app.SystemConfigurationPanel, 'numeric');
            app.OpticalEfficiencyGSEditField.ValueChangedFcn = createCallbackFcn(app, @OpticalEfficiencyGSEditFieldValueChanged, true);
            app.OpticalEfficiencyGSEditField.Value = -0.97;
            app.RxApertureD_rxmLabel = uilabel(app.SystemConfigurationPanel);
            app.RxApertureD_rxmLabel.Text = 'Antenna Aperture [m]';
            app.AntennaApertureSatEditField = uieditfield(app.SystemConfigurationPanel, 'numeric');
            app.AntennaApertureSatEditField.ValueChangedFcn = createCallbackFcn(app, @AntennaApertureSatEditFieldValueChanged, true);
            app.AntennaApertureSatEditField.Value = 0.08;
            app.SystemLossL_sysdBLabel = uilabel(app.SystemConfigurationPanel);
            app.SystemLossL_sysdBLabel.Text = 'Optical Efficiency [dB]';
            app.OpticalEfficiencySatEditField = uieditfield(app.SystemConfigurationPanel, 'numeric');
            app.OpticalEfficiencySatEditField.ValueChangedFcn = createCallbackFcn(app, @OpticalEfficiencySatEditFieldValueChanged, true);
            app.OpticalEfficiencySatEditField.Value = -0.97;
            app.OrbitAltitudeHkmLabel = uilabel(app.SystemConfigurationPanel);
            app.OrbitAltitudeHkmLabel.Text = 'Pointing Error [rad]';
            app.PointingErrorSatEditField = uieditfield(app.SystemConfigurationPanel, 'numeric');
            app.PointingErrorSatEditField.ValueChangedFcn = createCallbackFcn(app, @PointingErrorSatEditFieldValueChanged, true);
            app.PointingErrorSatEditField.Value = 1e-06;
            app.OrbitAltitudekmLabel = uilabel(app.SystemConfigurationPanel);
            app.OrbitAltitudekmLabel.Text = 'Orbit Altitude [km]';
            app.OrbitAltitudeSatEditField = uieditfield(app.SystemConfigurationPanel, 'numeric');
            app.OrbitAltitudeSatEditField.ValueChangedFcn = createCallbackFcn(app, @OrbitAltitudeSatEditFieldValueChanged, true);
            app.OrbitAltitudeSatEditField.Value = 500;
            app.AltitudekmLabel = uilabel(app.SystemConfigurationPanel);
            app.AltitudekmLabel.Text = 'Altitude [km]';
            app.AltitudeGSEditField = uieditfield(app.SystemConfigurationPanel, 'numeric');
            app.AltitudeGSEditField.ValueChangedFcn = createCallbackFcn(app, @AltitudeGSEditFieldValueChanged, true);
            app.AltitudeGSEditField.Value = 0.1;
            app.PointingError_GsradLabel = uilabel(app.SystemConfigurationPanel);
            app.PointingError_GsradLabel.Text = 'Pointing Error [rad]';
            app.PointingErrorGSEditField = uieditfield(app.SystemConfigurationPanel, 'numeric');
            app.PointingErrorGSEditField.ValueChangedFcn = createCallbackFcn(app, @PointingErrorGSEditFieldValueChanged, true);
            app.PointingErrorGSEditField.Value = 1e-06;
            app.GroundStationLabel = uilabel(app.SystemConfigurationPanel);
            app.GroundStationLabel.Text = 'Ground Station';
            app.GlobalParametersLabel = uilabel(app.SystemConfigurationPanel);
            app.GlobalParametersLabel.Text = 'Global Parameters';
            app.SatelliteLabel = uilabel(app.SystemConfigurationPanel);
            app.SatelliteLabel.Text = 'Satellite';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExportResultsButton = uibutton(app.UIFigure, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.ChannelEnvironmentPanel = uipanel(app.UIFigure);
            app.ChannelEnvironmentPanel.Title = 'Channel & Environment';
            app.Panel_4 = uipanel(app.ChannelEnvironmentPanel);
            app.Switch_2 = uiswitch(app.Panel_4, 'toggle');
            app.Switch_2.Items = {'Uplink (Ground-to-Sat)', 'Downlink (Sat-to-Ground)'};
            app.Switch_2.Value = 'Uplink (Ground-to-Sat)';
            app.TabGroup = uitabgroup(app.ChannelEnvironmentPanel);
            app.WeatherTab = uitab(app.TabGroup);
            app.WeatherTab.Title = 'Weather';
            app.WeatherConditionDropDownLabel = uilabel(app.WeatherTab);
            app.WeatherConditionDropDownLabel.Text = 'Weather Condition';
            app.WeatherDropDownValueChanged = uidropdown(app.WeatherTab);
            app.WeatherDropDownValueChanged.Items = {'Clear Sky', 'Fog', 'Rain', 'Snow', 'Clouds'};
            app.WeatherDropDownValueChanged.ValueChangedFcn = createCallbackFcn(app, @WeatherDropDownValueChangedValueChanged, true);
            app.WeatherDropDownValueChanged.Value = 'Clear Sky';
            app.GeometryTextAreaLabel_2 = uilabel(app.WeatherTab);
            app.GeometryTextAreaLabel_2.Text = 'Geometry';
            app.GeometryTextArea_2 = uitextarea(app.WeatherTab);
            app.ParameterField = uieditfield(app.WeatherTab, 'numeric');
            app.ParameterLabel = uilabel(app.WeatherTab);
            app.ParameterLabel.Text = 'Additional Loss [dB]';
            app.SnowTypeButtonGroup = uibuttongroup(app.WeatherTab);
            app.DrySnowButton = uiradiobutton(app.SnowTypeButtonGroup);
            app.DrySnowButton.Text = 'Dry Snow';
            app.DrySnowButton.Value = true;
            app.WetSnowButton = uiradiobutton(app.SnowTypeButtonGroup);
            app.WetSnowButton.Text = 'Wet Snow';
            app.TurbulenceTab = uitab(app.TabGroup);
            app.TurbulenceTab.Title = 'Turbulence';
            app.GeometryTextArea_4 = uitextarea(app.TurbulenceTab);
            app.GeometryTextAreaLabel_4 = uilabel(app.TurbulenceTab);
            app.GeometryTextAreaLabel_4.Text = 'Ground Wind Speed';
            app.RMSWindSpeedvmsEditFieldLabel = uilabel(app.TurbulenceTab);
            app.RMSWindSpeedvmsEditFieldLabel.Text = 'RMS Wind Speed (v) [m/s]';
            app.RMSWindSpeedvmsEditField = uieditfield(app.TurbulenceTab, 'numeric');
            app.RMSWindSpeedvmsEditField.Value = 21;
            app.HV57Label = uilabel(app.TurbulenceTab);
            app.HV57Label.Text = 'H-V 5/7 ';
            app.TurbulenceModelSwitchLabel = uilabel(app.TurbulenceTab);
            app.TurbulenceModelSwitchLabel.Text = 'Turbulence Model';
            app.TurbulenceModelSwitch = uiswitch(app.TurbulenceTab, 'slider');
            app.TurbulenceModelSwitch.ValueChangedFcn = createCallbackFcn(app, @TurbulenceModelSwitchValueChanged, true);
            app.TurbulenceLamp = uilamp(app.TurbulenceTab);
            app.LinkDirectionLabel = uilabel(app.ChannelEnvironmentPanel);
            app.LinkDirectionLabel.Text = 'Link Direction';
            app.LinkBudgetAnalysisPanel = uipanel(app.UIFigure);
            app.LinkBudgetAnalysisPanel.Title = 'Link Budget Analysis';
            app.TransceiverTextAreaLabel_2 = uilabel(app.LinkBudgetAnalysisPanel);
            app.TransceiverTextAreaLabel_2.Text = 'Detailed Link Budget';
            app.SummaryCardPanel = uipanel(app.LinkBudgetAnalysisPanel);
            app.SummaryCardPanel.Title = 'Summary Card';
            app.RxPowerLabel = uilabel(app.SummaryCardPanel);
            app.RxPowerLabel.Text = 'Rx Power: 0 dBm';
            app.LinkMarginLabel = uilabel(app.SummaryCardPanel);
            app.LinkMarginLabel.Text = 'Link Margin: 0 dB';
            app.Panel_6 = uipanel(app.LinkBudgetAnalysisPanel);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.TabGroup2 = uitabgroup(app.VisualizationPanel);
            app.RxPowerSlantRangevsElevationTab = uitab(app.TabGroup2);
            app.RxPowerSlantRangevsElevationTab.Title = 'Rx Power & Slant Range vs. Elevation';
            app.UIAxes1 = uiaxes(app.RxPowerSlantRangevsElevationTab);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.LinkMarginvsWeatherTab = uitab(app.TabGroup2);
            app.LinkMarginvsWeatherTab.Title = 'Link Margin vs. Weather';
            app.UIAxes2 = uiaxes(app.LinkMarginvsWeatherTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LossBreakdownTab = uitab(app.TabGroup2);
            app.LossBreakdownTab.Title = 'Loss Breakdown';
            app.UIAxes3 = uiaxes(app.LossBreakdownTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SGLC_Calc_V1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end